classdef ABCD_Matrix_Solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputandOutputImpedencesPanel   matlab.ui.container.Panel
        InputElementListBoxLabel        matlab.ui.control.Label
        InputImpedenceListBox           matlab.ui.control.ListBox
        InputImpedenceLabel_2           matlab.ui.control.Label
        InputImpedenceSlider            matlab.ui.control.Slider
        OutputImpedenceSliderLabel      matlab.ui.control.Label
        OutputImpedenceSlider           matlab.ui.control.Slider
        InputImpedenceLabel             matlab.ui.control.Label
        OutputImpedenceLabel            matlab.ui.control.Label
        ABCDMatrixCircuitElementsPanel  matlab.ui.container.Panel
        MatrixTypeDropDownLabel         matlab.ui.control.Label
        MatrixTypeDropDown              matlab.ui.control.DropDown
        SelectMatrixLabel               matlab.ui.control.Label
        Matrix1Button                   matlab.ui.control.Button
        Matrix2Button                   matlab.ui.control.Button
        Matrix3Button                   matlab.ui.control.Button
        MatrixFormulaAxes               matlab.ui.control.UIAxes
        EnterMatrixButton               matlab.ui.control.Button
        TransformernEditFieldLabel      matlab.ui.control.Label
        TransformerEditField            matlab.ui.control.NumericEditField
        TransmissionLinekmLabel         matlab.ui.control.Label
        TransformerEditField_2          matlab.ui.control.NumericEditField
        TransmissionLinedmLabel         matlab.ui.control.Label
        TransformerEditField_3          matlab.ui.control.NumericEditField
        Matrix1Axes                     matlab.ui.control.UIAxes
        Matrix2Axes                     matlab.ui.control.UIAxes
        Matrix3Axes                     matlab.ui.control.UIAxes
        OutputAxes                      matlab.ui.control.UIAxes
        ABCDMatrixImpedencePanel        matlab.ui.container.Panel
        ABCDElementLabel                matlab.ui.control.Label
        ABCDImpedenceListBox            matlab.ui.control.ListBox
        ABCDImpdenceSliderLabel         matlab.ui.control.Label
        ABCDImpedenceSlider             matlab.ui.control.Slider
        ABCDImpdenceLabel               matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.EditField
        ViewCodeButton                  matlab.ui.control.Button
        CalculateOutputButton           matlab.ui.control.Button
        InputImpedenceAxes              matlab.ui.control.UIAxes
        InputImpedenceAxes_2            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Zin; %Input impedence
        Zout; %Output Impedence
        Zintype = 'R'; %The Type of Impedence the input is currently set to
        Zabcdtype = 'R';
        MatrixSelect = '1';
    end


    methods (Access = private)

        % Value changed function: InputImpedenceListBox
        function InputImpedenceListBoxValueChanged(app, event)
            value = app.InputImpedenceListBox.Value;
            app.InputImpedenceAxes.XAxis.TickLabels = {}; %Turn off axes tick marks
            app.InputImpedenceAxes.YAxis.TickLabels = {};
            %I compare this first index of the given string because matlab doesn't like comparing strings of different len
           app.InputImpedenceAxes.reset; %We need to reset the axis to put a new image with appdesigner
           if value(1) == 'R'
                app.InputImpedenceLabel.Text = 'Ohms';
                app.Zintype = 'R'; %Keeps track of which setting selected
                %Grabs appropriate image and stretches it to fit
                I = imshow('./Images/InputR.JPG', 'Parent', app.InputImpedenceAxes,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
            elseif value(1) == 'C'
                app.InputImpedenceLabel.Text = 'uF';
                app.Zintype = 'C';
                I = imshow('./Images/InputC.JPG', 'Parent', app.InputImpedenceAxes,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
            else
                app.InputImpedenceLabel.Text = 'mH';
                app.Zintype = 'L';
                I = imshow('./Images/InputL.jpg', 'Parent', app.InputImpedenceAxes,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
            end
 
            app.InputImpedenceAxes.XLim = [0 I.XData(2)];
            app.InputImpedenceAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: InputImpedenceSlider
        function InputImpedenceSliderValueChanged(app, event)
            value = app.InputImpedenceSlider.Value;
            app.Zin = value;
        end

        % Value changed function: OutputImpedenceSlider
        function OutputImpedenceSliderValueChanged(app, event)
            value = app.OutputImpedenceSlider.Value;
            app.Zout = value;
        end

        % Value changed function: ABCDImpedenceListBox
        function ABCDImpedenceListBoxValueChanged(app, event)
            value = app.ABCDImpedenceListBox.Value;
            %I compare this first index of the given string because matlab doesn't like comparing strings of different len
            if value(1) == 'R'
                app.ABCDImpdenceLabel.Text = 'Ohms';
                app.Zabcdtype = 'R'; %Keeps track of which setting selected
                app.ZEditField.Value = 'R';
            elseif value(1) == 'C'
                app.ABCDImpdenceLabel.Text = 'uF';
                app.Zabcdtype = 'C';
                app.ZEditField.Value = '-1i/(w*C)';
            else
                app.ABCDImpdenceLabel.Text = 'mH';
                app.Zabcdtype = 'L';
                app.ZEditField.Value = '1i*w*L';
            end
        end

        % Value changed function: MatrixTypeDropDown
        function MatrixTypeDropDownValueChanged(app, event)
            value = app.MatrixTypeDropDown.Value;
            app.MatrixFormulaAxes.XAxis.TickLabels = {};
            app.MatrixFormulaAxes.YAxis.TickLabels = {};
            % Decide which image to display and stretch it to axes
            app.MatrixFormulaAxes.reset;
            if value(1:3) == 'Ser'
                I = imshow('./Images/Series_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);
            elseif value(1:3) == 'Shu'
                I = imshow('./Images/Shunt_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);
            elseif value(1:3) == 'Tra'
                I = imshow('./Images/Transformer_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);
            else
                I = imshow('./Images/TransmissionLine_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);  
            end
                
                % Set limits of axes
            app.MatrixFormulaAxes.XLim = [0 I.XData(2)];
            app.MatrixFormulaAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: Matrix1Button
        function Matrix1ButtonPushed(app, event)
            app.Matrix1Axes.reset;
            app.MatrixSelect = '1';
        end

        % Button pushed function: Matrix2Button
        function Matrix2ButtonPushed(app, event)
            app.Matrix2Axes.reset;
            app.MatrixSelect = '2';
        end

        % Button pushed function: Matrix3Button
        function Matrix3ButtonPushed(app, event)
            app.Matrix3Axes.reset;
            app.MatrixSelect = '3';
        end

        % Button pushed function: EnterMatrixButton
        function EnterMatrixButtonPushed(app, event)
            if app.MatrixSelect == '1'
               axes = app.Matrix1Axes; 
               axes.reset;
            end
            axes.XAxis.TickLabels = {}; %Turn off axes tick marks
            axes.YAxis.TickLabels = {};
            if app.Zabcdtype == 'R' & app.MatrixTypeDropDown.Value == 'Series'
                 I = imshow('./Images/SeriesR.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
            end
            
            app.Matrix1Axes.XLim = [0 I.XData(2)];
            app.Matrix1Axes.YLim = [0 I.YData(2)];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputandOutputImpedencesPanel = uipanel(app.UIFigure);
            app.InputandOutputImpedencesPanel.Title = 'Input and Output Impedences';
            app.InputElementListBoxLabel = uilabel(app.InputandOutputImpedencesPanel);
            app.InputElementListBoxLabel.Text = 'Input Element';
            app.InputImpedenceListBox = uilistbox(app.InputandOutputImpedencesPanel);
            app.InputImpedenceListBox.Items = {'Resistor', 'Capacitor', 'Inductor'};
            app.InputImpedenceListBox.ValueChangedFcn = createCallbackFcn(app, @InputImpedenceListBoxValueChanged, true);
            app.InputImpedenceListBox.Value = 'Resistor';
            app.InputImpedenceLabel_2 = uilabel(app.InputandOutputImpedencesPanel);
            app.InputImpedenceLabel_2.Text = {'Input'; 'Impedence'};
            app.InputImpedenceSlider = uislider(app.InputandOutputImpedencesPanel);
            app.InputImpedenceSlider.ValueChangedFcn = createCallbackFcn(app, @InputImpedenceSliderValueChanged, true);
            app.OutputImpedenceSliderLabel = uilabel(app.InputandOutputImpedencesPanel);
            app.OutputImpedenceSliderLabel.Text = {'Output'; 'Impedence'};
            app.OutputImpedenceSlider = uislider(app.InputandOutputImpedencesPanel);
            app.OutputImpedenceSlider.ValueChangedFcn = createCallbackFcn(app, @OutputImpedenceSliderValueChanged, true);
            app.InputImpedenceLabel = uilabel(app.UIFigure);
            app.InputImpedenceLabel.Text = 'Ohms';
            app.OutputImpedenceLabel = uilabel(app.UIFigure);
            app.OutputImpedenceLabel.Text = 'Ohms';
            app.ABCDMatrixCircuitElementsPanel = uipanel(app.UIFigure);
            app.ABCDMatrixCircuitElementsPanel.Title = 'ABCD Matrix Circuit Elements';
            app.MatrixTypeDropDownLabel = uilabel(app.ABCDMatrixCircuitElementsPanel);
            app.MatrixTypeDropDownLabel.Text = 'Matrix Type';
            app.MatrixTypeDropDown = uidropdown(app.ABCDMatrixCircuitElementsPanel);
            app.MatrixTypeDropDown.Items = {'Series', 'Shunt', 'T.Line', 'Transformer'};
            app.MatrixTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrixTypeDropDownValueChanged, true);
            app.MatrixTypeDropDown.Value = 'Series';
            app.SelectMatrixLabel = uilabel(app.ABCDMatrixCircuitElementsPanel);
            app.SelectMatrixLabel.Text = 'Select Matrix';
            app.Matrix1Button = uibutton(app.ABCDMatrixCircuitElementsPanel, 'push');
            app.Matrix1Button.ButtonPushedFcn = createCallbackFcn(app, @Matrix1ButtonPushed, true);
            app.Matrix1Button.Text = '1';
            app.Matrix2Button = uibutton(app.ABCDMatrixCircuitElementsPanel, 'push');
            app.Matrix2Button.ButtonPushedFcn = createCallbackFcn(app, @Matrix2ButtonPushed, true);
            app.Matrix2Button.Text = '2';
            app.Matrix3Button = uibutton(app.ABCDMatrixCircuitElementsPanel, 'push');
            app.Matrix3Button.ButtonPushedFcn = createCallbackFcn(app, @Matrix3ButtonPushed, true);
            app.Matrix3Button.Text = '3';
            app.MatrixFormulaAxes = uiaxes(app.ABCDMatrixCircuitElementsPanel);
            title(app.MatrixFormulaAxes, 'Matrix Layout')
            app.MatrixFormulaAxes.XLim = [0 1];
            app.MatrixFormulaAxes.YLim = [0 1];
            app.MatrixFormulaAxes.ZLim = [0 1];
            app.MatrixFormulaAxes.CLim = [0 1];
            app.MatrixFormulaAxes.XTick = [0 0.5 1];
            app.MatrixFormulaAxes.YTick = [0 0.5 1];
            app.MatrixFormulaAxes.ZTick = [0 0.5 1];
            app.EnterMatrixButton = uibutton(app.ABCDMatrixCircuitElementsPanel, 'push');
            app.EnterMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @EnterMatrixButtonPushed, true);
            app.EnterMatrixButton.Text = 'Enter Matrix';
            app.TransformernEditFieldLabel = uilabel(app.ABCDMatrixCircuitElementsPanel);
            app.TransformernEditFieldLabel.Text = {'Transformer'; 'n = '};
            app.TransformerEditField = uieditfield(app.ABCDMatrixCircuitElementsPanel, 'numeric');
            app.TransformerEditField.Limits = [1 30];
            app.TransformerEditField.ValueDisplayFormat = '%.0f';
            app.TransformerEditField.Value = 1;
            app.TransmissionLinekmLabel = uilabel(app.ABCDMatrixCircuitElementsPanel);
            app.TransmissionLinekmLabel.Text = {'Transmission'; 'Line km = '};
            app.TransformerEditField_2 = uieditfield(app.ABCDMatrixCircuitElementsPanel, 'numeric');
            app.TransformerEditField_2.Limits = [0 50];
            app.TransformerEditField_2.ValueDisplayFormat = '%.0f';
            app.TransmissionLinedmLabel = uilabel(app.ABCDMatrixCircuitElementsPanel);
            app.TransmissionLinedmLabel.Text = {'Transmission'; 'Line dm ='};
            app.TransformerEditField_3 = uieditfield(app.ABCDMatrixCircuitElementsPanel, 'numeric');
            app.TransformerEditField_3.Limits = [0 30];
            app.TransformerEditField_3.ValueDisplayFormat = '%.0f';
            app.Matrix1Axes = uiaxes(app.UIFigure);
            title(app.Matrix1Axes, 'Matrix 1')
            app.Matrix1Axes.XLim = [0 1];
            app.Matrix1Axes.YLim = [0 1];
            app.Matrix1Axes.ZLim = [0 1];
            app.Matrix1Axes.CLim = [0 1];
            app.Matrix1Axes.XTick = [0 0.5 1];
            app.Matrix1Axes.YTick = [0 0.5 1];
            app.Matrix1Axes.ZTick = [0 0.5 1];
            app.Matrix2Axes = uiaxes(app.UIFigure);
            title(app.Matrix2Axes, 'Matrix 2')
            app.Matrix2Axes.XLim = [0 1];
            app.Matrix2Axes.YLim = [0 1];
            app.Matrix2Axes.ZLim = [0 1];
            app.Matrix2Axes.CLim = [0 1];
            app.Matrix2Axes.XTick = [0 0.5 1];
            app.Matrix2Axes.YTick = [0 0.5 1];
            app.Matrix2Axes.ZTick = [0 0.5 1];
            app.Matrix3Axes = uiaxes(app.UIFigure);
            title(app.Matrix3Axes, 'Matrix 3')
            app.Matrix3Axes.XLim = [0 1];
            app.Matrix3Axes.YLim = [0 1];
            app.Matrix3Axes.ZLim = [0 1];
            app.Matrix3Axes.CLim = [0 1];
            app.Matrix3Axes.XTick = [0 0.5 1];
            app.Matrix3Axes.YTick = [0 0.5 1];
            app.Matrix3Axes.ZTick = [0 0.5 1];
            app.OutputAxes = uiaxes(app.UIFigure);
            title(app.OutputAxes, 'Vout/Vin')
            xlabel(app.OutputAxes, 'Frequency')
            ylabel(app.OutputAxes, 'Response')
            app.OutputAxes.XLim = [0 1];
            app.OutputAxes.YLim = [0 1];
            app.OutputAxes.ZLim = [0 1];
            app.OutputAxes.CLim = [0 1];
            app.OutputAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.OutputAxes.YTick = [0 0.5 1];
            app.OutputAxes.ZTick = [0 0.5 1];
            app.ABCDMatrixImpedencePanel = uipanel(app.UIFigure);
            app.ABCDMatrixImpedencePanel.Title = 'ABCD Matrix Impedence';
            app.ABCDElementLabel = uilabel(app.ABCDMatrixImpedencePanel);
            app.ABCDElementLabel.Text = 'ABCD Element';
            app.ABCDImpedenceListBox = uilistbox(app.ABCDMatrixImpedencePanel);
            app.ABCDImpedenceListBox.Items = {'Resistor', 'Capacitor', 'Inductor'};
            app.ABCDImpedenceListBox.ValueChangedFcn = createCallbackFcn(app, @ABCDImpedenceListBoxValueChanged, true);
            app.ABCDImpedenceListBox.Value = 'Resistor';
            app.ABCDImpdenceSliderLabel = uilabel(app.ABCDMatrixImpedencePanel);
            app.ABCDImpdenceSliderLabel.Text = {'ABCD'; 'Impedence'};
            app.ABCDImpedenceSlider = uislider(app.ABCDMatrixImpedencePanel);
            app.ABCDImpdenceLabel = uilabel(app.ABCDMatrixImpedencePanel);
            app.ABCDImpdenceLabel.Text = 'Ohms';
            app.ZEditFieldLabel = uilabel(app.ABCDMatrixImpedencePanel);
            app.ZEditFieldLabel.Text = 'Z =';
            app.ZEditField = uieditfield(app.ABCDMatrixImpedencePanel, 'text');
            app.ZEditField.Editable = 'off';
            app.ViewCodeButton = uibutton(app.ABCDMatrixImpedencePanel, 'push');
            app.ViewCodeButton.Text = 'View Code';
            app.CalculateOutputButton = uibutton(app.ABCDMatrixImpedencePanel, 'push');
            app.CalculateOutputButton.Text = 'Calculate Output';
            app.InputImpedenceAxes = uiaxes(app.UIFigure);
            title(app.InputImpedenceAxes, 'Input Z')
            app.InputImpedenceAxes.XLim = [0 1];
            app.InputImpedenceAxes.YLim = [0 1];
            app.InputImpedenceAxes.ZLim = [0 1];
            app.InputImpedenceAxes.CLim = [0 1];
            app.InputImpedenceAxes.XTick = [0 0.5 1];
            app.InputImpedenceAxes.YTick = [0 0.5 1];
            app.InputImpedenceAxes.ZTick = [0 0.5 1];
            app.InputImpedenceAxes_2 = uiaxes(app.UIFigure);
            title(app.InputImpedenceAxes_2, 'Output Z')
            app.InputImpedenceAxes_2.XLim = [0 1];
            app.InputImpedenceAxes_2.YLim = [0 1];
            app.InputImpedenceAxes_2.ZLim = [0 1];
            app.InputImpedenceAxes_2.CLim = [0 1];
            app.InputImpedenceAxes_2.XTick = [0 0.5 1];
            app.InputImpedenceAxes_2.YTick = [0 0.5 1];
            app.InputImpedenceAxes_2.ZTick = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = ABCD_Matrix_Solver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end